classdef QuarantineQuestionnaire < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatientsDisplayUIFigure       matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        questionNumber_6              matlab.ui.container.Panel
        YesCheckBox_6                 matlab.ui.control.CheckBox
        NoCheckBox_6                  matlab.ui.control.CheckBox
        questionNumber_5              matlab.ui.container.Panel
        YesCheckBox_5                 matlab.ui.control.CheckBox
        NoCheckBox_5                  matlab.ui.control.CheckBox
        questionNumber_4              matlab.ui.container.Panel
        YesCheckBox_4                 matlab.ui.control.CheckBox
        NoCheckBox_4                  matlab.ui.control.CheckBox
        questionNumber_3              matlab.ui.container.Panel
        YesCheckBox_3                 matlab.ui.control.CheckBox
        NoCheckBox_3                  matlab.ui.control.CheckBox
        questionNumber_2              matlab.ui.container.Panel
        YesCheckBox_2                 matlab.ui.control.CheckBox
        NoCheckBox_2                  matlab.ui.control.CheckBox
        SUBMITButton                  matlab.ui.control.Button
        HealthQuestionsLabel          matlab.ui.control.Label
        questionNumber_1              matlab.ui.container.Panel
        DayEditField                  matlab.ui.control.NumericEditField
        DayEditFieldLabel             matlab.ui.control.Label
        DegreesinFahrenheitEditField  matlab.ui.control.NumericEditField
        DegreesinFahrenheitEditFieldLabel  matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        PlotTab                       matlab.ui.container.Tab
        Hyperlink                     matlab.ui.control.Hyperlink
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        RedQuarantineContactDoctorLabel  matlab.ui.control.Label
        GreenSafetogooutLamp          matlab.ui.control.Lamp
        GreenSafetogooutLampLabel     matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        DataTab                       matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        UITable                       matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shipped with the product.
    
    %HELLO DR. KENNY AND TA!
    %THESE ARE THE INSTRUCTIONS FOR HOW TO WORK OUR GUI
    %INPUT YOUR TEMPERATURE FOR DAY 1 AND FILL OUT THE QUESTIONNAIRE
    %PRESS THE BLUE SUBMIT BUTTON, AND YOUR DATA WILL APPEAR ON THE GRAPH
    %IF A GREEN LIGHT APPEARS, YOU ARE GOOD TO GO IN PUBLIC
    %IF A RED LIGHT APPEARS, YOU SHOULD STAY INSIDE AND CONTACT A DOCTOR,
    %YOU CAN CONTACT A DR. BY CLICKING THE HYPERLINK WHICH TAKES YOU DIRECTLY TO
    %DOCTORS IN YOUR AREA(PRETTY COOL RIGHT)
    %YOU CAN INPUT ALL OF YOUR TEMPERATURES UP TO 7 DAYS
    %**cough cough** NOTICE OUR WAKE FOREST AND QQ (QUARANTINE QUESTIONNAIRE) LOGOS
    %HAVE A NICE BREAK, THANK YOU :) -WESLEY

    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        questionaire = 0;
    end
    
    
    methods (Access = private)
        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PatientsDisplayUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {400, 400};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {282, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function SliderValueChanging(app, event)

        end

        % Callback function
        function refreshplot(app, event)
            %Graph temperatures 
            if app.DayEditField.Value >= 1 
            
            T = readtable("myPatientData.dat","TextType","string");
            

            x = table2array(T(:,"Days_2"));
            y = table2array(T(:,"Temperature_1"));

            %plot(app.UIAxes,x,y), "-o": indicates shape of coordinate
            plot(app.UIAxes,x,y,"-or");
            end
        end

        % Callback function
        function updateSelectedGenders(app, event)
      
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)

        end

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            %Determine if questionaire is > 2, then patient has covid
            if app.questionaire > 2
                app.GreenSafetogooutLamp.Color = [1,0,0];
            elseif app.questionaire < 2
                app.GreenSafetogooutLamp.Color = [0,1,0];
            end

            %Determine temperature of patient, adds to data table and file
            if app.DayEditField.Value <= 7
                %Writes user input values into data table
                app.UITable.Data = [app.UITable.Data; app.DegreesinFahrenheitEditField.Value app.DayEditField.Value];

                %Save user input into a file
                patientInfo = {};
                Temperature = [app.UITable.Data];
                Days = [app.UITable.Data];
                
                T = table(Temperature,Days,'RowNames',patientInfo);
                
                writetable(T,'myPatientData.dat')
                type myPatientData.dat
            end
            
            %Calls function to plot patient info on graph
            refreshplot(app)
        end

        % Value changed function: YesCheckBox_2
        function YesCheckBox_2ValueChanged(app, event)
             app.questionaire = app.questionaire - 1;
        end

        % Value changed function: YesCheckBox_3
        function YesCheckBox_3ValueChanged(app, event)
             app.questionaire = app.questionaire + 1;
        end

        % Value changed function: YesCheckBox_4
        function YesCheckBox_4ValueChanged(app, event)
            app.questionaire = app.questionaire + 1;
        end

        % Value changed function: YesCheckBox_5
        function YesCheckBox_5ValueChanged(app, event)
            app.questionaire = app.questionaire + 1;
        end

        % Value changed function: YesCheckBox_6
        function YesCheckBox_6ValueChanged(app, event)
            app.questionaire = app.questionaire + 1;
        end

        % Callback function
        function NoCheckBox_2ValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatientsDisplayUIFigure = uifigure('Visible', 'off');
            app.PatientsDisplayUIFigure.Name = 'Patients Display';
            app.PatientsDisplayUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PatientsDisplayUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.questionNumber_1 = uipanel(app.LeftPanel);
            app.questionNumber_1.Title = 'What is your temperature?';
            app.DegreesinFahrenheitEditFieldLabel = uilabel(app.questionNumber_1);
            app.DegreesinFahrenheitEditFieldLabel.Text = 'Degrees in Fahrenheit';
            app.DegreesinFahrenheitEditField = uieditfield(app.questionNumber_1, 'numeric');
            app.DegreesinFahrenheitEditField.Limits = [95 110];
            app.DegreesinFahrenheitEditField.Value = 95;
            app.DayEditFieldLabel = uilabel(app.questionNumber_1);
            app.DayEditFieldLabel.Text = 'Day';
            app.DayEditField = uieditfield(app.questionNumber_1, 'numeric');
            app.DayEditField.Limits = [1 7];
            app.DayEditField.ValueDisplayFormat = '%11g';
            app.DayEditField.Value = 1;
            app.HealthQuestionsLabel = uilabel(app.LeftPanel);
            app.HealthQuestionsLabel.Text = 'Health Questions';
            app.SUBMITButton = uibutton(app.LeftPanel, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.questionNumber_2 = uipanel(app.LeftPanel);
            app.questionNumber_2.Title = 'Have you been wearing a mask?';
            app.NoCheckBox_2 = uicheckbox(app.questionNumber_2);
            app.NoCheckBox_2.Text = 'No';
            app.YesCheckBox_2 = uicheckbox(app.questionNumber_2);
            app.YesCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @YesCheckBox_2ValueChanged, true);
            app.YesCheckBox_2.Text = 'Yes';
            app.questionNumber_3 = uipanel(app.LeftPanel);
            app.questionNumber_3.Title = 'Do you have a cough?';
            app.NoCheckBox_3 = uicheckbox(app.questionNumber_3);
            app.NoCheckBox_3.Text = 'No';
            app.YesCheckBox_3 = uicheckbox(app.questionNumber_3);
            app.YesCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @YesCheckBox_3ValueChanged, true);
            app.YesCheckBox_3.Text = 'Yes';
            app.questionNumber_4 = uipanel(app.LeftPanel);
            app.questionNumber_4.Title = 'Have you been in contact with a positive case in the last 14 days?';
            app.NoCheckBox_4 = uicheckbox(app.questionNumber_4);
            app.NoCheckBox_4.Text = 'No';
            app.YesCheckBox_4 = uicheckbox(app.questionNumber_4);
            app.YesCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @YesCheckBox_4ValueChanged, true);
            app.YesCheckBox_4.Text = 'Yes';
            app.questionNumber_5 = uipanel(app.LeftPanel);
            app.questionNumber_5.Title = 'Do you have shortness of breath or difficulty breathing?';
            app.NoCheckBox_5 = uicheckbox(app.questionNumber_5);
            app.NoCheckBox_5.Text = 'No';
            app.YesCheckBox_5 = uicheckbox(app.questionNumber_5);
            app.YesCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @YesCheckBox_5ValueChanged, true);
            app.YesCheckBox_5.Text = 'Yes';
            app.questionNumber_6 = uipanel(app.LeftPanel);
            app.questionNumber_6.Title = 'Have you recently lost your sense of taste or smell?';
            app.NoCheckBox_6 = uicheckbox(app.questionNumber_6);
            app.NoCheckBox_6.Text = 'No';
            app.YesCheckBox_6 = uicheckbox(app.questionNumber_6);
            app.YesCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @YesCheckBox_6ValueChanged, true);
            app.YesCheckBox_6.Text = 'Yes';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Tooltip = {''; ''; ''};
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, {'Temperature Vs Days'; ''})
            xlabel(app.UIAxes, 'Days')
            ylabel(app.UIAxes, 'Temperature in Degrees Fahrenheit')
            app.UIAxes.XLim = [0 7];
            app.UIAxes.YLim = [95 110];
            app.UIAxes.CLim = [0 90];
            app.UIAxes.ALim = [0 90];
            app.UIAxes.XTick = [1 2 3 4 5 6 7];
            app.UIAxes.XTickLabel = {'S'; 'M'; 'T'; 'W'; 'T'; 'F'; 'S'};
            app.UIAxes.YTick = [95 100 105 110];
            app.UIAxes.YTickLabel = {'95'; '100'; '105'; '110'};
            app.GreenSafetogooutLampLabel = uilabel(app.PlotTab);
            app.GreenSafetogooutLampLabel.Text = 'Green: Safe to go out';
            app.GreenSafetogooutLamp = uilamp(app.PlotTab);
            app.GreenSafetogooutLamp.Tag = 'Lamp';
            app.RedQuarantineContactDoctorLabel = uilabel(app.PlotTab);
            app.RedQuarantineContactDoctorLabel.Text = 'Red: Quarantine & Contact Doctor';
            app.Image = uiimage(app.PlotTab);
            app.Image.ImageSource = 'wfcovid.png';
            app.Image2 = uiimage(app.PlotTab);
            app.Image2.ImageSource = 'Screen Shot 2021-12-10 at 10.46.42 PM.png';
            app.Hyperlink = uihyperlink(app.PlotTab);
            app.Hyperlink.URL = 'https://doctor.webmd.com/';
            app.Hyperlink.Text = 'Click for Doctors near you';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.GridLayout2 = uigridlayout(app.DataTab);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'TEMPERATURE'; 'Days'};
            app.UITable.RowName = {''; ''};
            app.UITable.SelectionType = 'column';
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            % Show the figure after all components are created
            app.PatientsDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuarantineQuestionnaire

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatientsDisplayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatientsDisplayUIFigure)
        end
    end
end